classdef Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        DilationlevelRpRiLabel  matlab.ui.control.Label
        radioSlider             matlab.ui.control.Slider
        ImageDropDownLabel      matlab.ui.control.Label
        ImageDD                 matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global segm_data
            
            csv_file = '..\samples\segm.csv';
            segm_data = xlsread(csv_file, 1, 'B2:G7');
            [~, Files, ~] = xlsread(csv_file, 1, 'A2:A7');
            
            app.ImageDD.Items = Files;
            ImageDDValueChanged(app)
        end

        % Value changed function: radioSlider
        function radioSliderValueChanged(app, event)
            global im1 pupil_xyr iris_xyr
            
            dil = app.radioSlider.Value;
            
            im2 = change_dilation(im1, dil, pupil_xyr, iris_xyr);
            imshow(im2,'Parent',app.UIAxes)
        end

        % Value changed function: ImageDD
        function ImageDDValueChanged(app, event)
            global segm_data im1 pupil_xyr iris_xyr
            value = app.ImageDD.Value;
            arch = ['../samples/', value];
            im1 = iread(arch,'double');
            imshow(im1,'Parent',app.UIAxes)
            
            ind = find(contains(app.ImageDD.Items, value));
            pupil_xyr = segm_data(ind, 1:3);
            iris_xyr = segm_data(ind, 4:6);
            app.radioSlider.Value = pupil_xyr(3)/iris_xyr(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Artificially Changed Iris Dilation')
            app.DilationlevelRpRiLabel = uilabel(app.UIFigure);
            app.DilationlevelRpRiLabel.Text = 'Dilation level (Rp/Ri):';
            app.radioSlider = uislider(app.UIFigure);
            app.radioSlider.Limits = [0.1 0.9];
            app.radioSlider.ValueChangedFcn = createCallbackFcn(app, @radioSliderValueChanged, true);
            app.radioSlider.Value = 0.5;
            app.ImageDropDownLabel = uilabel(app.UIFigure);
            app.ImageDropDownLabel.Text = 'Image:';
            app.ImageDD = uidropdown(app.UIFigure);
            app.ImageDD.Items = {'LFVL.png', 'LVL.png', 'mask_1.png', 'mask_2.png', 'S1008R02.png', 'S1028R02.png'};
            app.ImageDD.ValueChangedFcn = createCallbackFcn(app, @ImageDDValueChanged, true);
            app.ImageDD.Value = 'LVL.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end